classdef fp_processing_vesion3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CommonTab                       matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        PeaksEventEditField             matlab.ui.control.NumericEditField
        PeaksEventEditFieldLabel        matlab.ui.control.Label
        EventsminEditField              matlab.ui.control.NumericEditField
        EventsminEditFieldLabel         matlab.ui.control.Label
        ManuallyaddremoveeventsPanel    matlab.ui.container.Panel
        ConfirmButton                   matlab.ui.control.Button
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        AbortButton                     matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        HighPassCutoffHzEditField       matlab.ui.control.NumericEditField
        HighPassCutoffHzEditFieldLabel  matlab.ui.control.Label
        MaxTimesEditField               matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel          matlab.ui.control.Label
        MinTimesEditField               matlab.ui.control.NumericEditField
        MinTimesEditFieldLabel          matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        Thresh2EditField                matlab.ui.control.NumericEditField
        Thresh2EditFieldLabel           matlab.ui.control.Label
        Thresh1EditField                matlab.ui.control.NumericEditField
        Thresh1EditFieldLabel           matlab.ui.control.Label
        RangeSlider                     matlab.ui.control.RangeSlider
        DetectButton                    matlab.ui.control.Button
        MinPeakProminenceEditField      matlab.ui.control.NumericEditField
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        BehavioralTab                   matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        CalculateBehavioralParametersButton  matlab.ui.control.Button
        ScaleCorrectionPanel            matlab.ui.container.Panel
        MobilityThreshEndcmsEditField   matlab.ui.control.NumericEditField
        MobilityThreshEndcmsEditFieldLabel  matlab.ui.control.Label
        MobilityThreshStartcmsEditField  matlab.ui.control.NumericEditField
        MobilityThreshStartcmsEditFieldLabel  matlab.ui.control.Label
        WidthpixelEditField             matlab.ui.control.NumericEditField
        WidthpixelEditFieldLabel        matlab.ui.control.Label
        HeightpixelEditField            matlab.ui.control.NumericEditField
        HeightpixelEditFieldLabel       matlab.ui.control.Label
        DiameterofHolescmEditField      matlab.ui.control.NumericEditField
        DiameterofHolescmEditFieldLabel  matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        LoadCoordinatesofHolesButton    matlab.ui.control.Button
        LoadBehavioralDataButton        matlab.ui.control.Button
        BehavioralSignalDataTrimPanel   matlab.ui.container.Panel
        KeepEndFrameorHeightWidthas0ifnotneededLabel  matlab.ui.control.Label
        Width2EditFieldLabel            matlab.ui.control.Label
        Width1EditFieldLabel            matlab.ui.control.Label
        Height2EditFieldLabel           matlab.ui.control.Label
        Height1EditFieldLabel           matlab.ui.control.Label
        EndFrameEditFieldLabel          matlab.ui.control.Label
        CreateTrimmedVideoButton        matlab.ui.control.Button
        StartFrameEditField             matlab.ui.control.NumericEditField
        EndFrameEditField               matlab.ui.control.NumericEditField
        Height1EditField                matlab.ui.control.NumericEditField
        Height2EditField                matlab.ui.control.NumericEditField
        Width1EditField                 matlab.ui.control.NumericEditField
        Width2EditField                 matlab.ui.control.NumericEditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        ResultsDisplayPanel             matlab.ui.container.Panel
        LatencytoTargetsEditField       matlab.ui.control.NumericEditField
        LatencytoTargetsEditFieldLabel  matlab.ui.control.Label
        DistanceTravelledcmEditField    matlab.ui.control.NumericEditField
        DistanceTravelledcmEditFieldLabel  matlab.ui.control.Label
        RewindButton                    matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        PlayPauseButton                 matlab.ui.control.StateButton
        SpeedSpinner                    matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        EditField_2                     matlab.ui.control.EditField
        SelectVideoButton               matlab.ui.control.Button
        Video                           matlab.ui.control.UIAxes
        LoadDataButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SelectPathButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        path, signal, signal_o, noise, thresh1, thresh2, framerate, mintime, maxtime, highf,...
            events_range, events_temp, Minpeak, flag, pks, pks_temp, pks_raw, locs, locs_temp,...
            w, w_temp, p, p_temp, z,InRange, InRange_temp, start_time, end_time, events_per_min,...
            peaks_per_event, input_video_file, vspeed, pp, vidObj, currentFrame, B, holexy, holexy_cm,...
            pos_head, pos_body, startFrame, endFrame, Height1, Height2, Width1, Width2, ...
            Trim_time, Dhole, height_maze, width_maze,mobi_start, mobi_end,velocity,...
            mobi_state, mobi_duration, immobi_duration,Latency_to_Target, Total_Dist, ishole,...
            within_hole, anglec, hole_order, exp_range, entry_count, ref
            % Variables
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            selpath = uigetdir;
            if selpath ~=0
                app.path = selpath;
                app.EditFieldValueChanged(event)
                cd(app.path)
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Value = app.path;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.signal_o = load([app.path,'\signal_corrected']).signal_corrected;
            app.noise = load([app.path,'\noise']).noise;
            app.FrameRateEditFieldValueChanged(event)
            if isfile([app.path,'\events_pks.mat'])
                app.signal = app.signal_o;
                app.events_range = load([app.path,'\events_pks.mat']).events_tosave;
                app.locs = load([app.path,'\events_pks.mat']).locs_tosave;
                app.z = zscore(app.signal);
                app.RangeSlider.Limits = [0, length(app.signal)];
                app.RangeSlider.Value = [0, length(app.signal)];
                cla(app.UIAxes,'reset')
                plot(app.UIAxes,app.z,'Color','k')
                xlim(app.UIAxes,app.RangeSlider.Value)
                hold(app.UIAxes,'on')
                if ~isempty(app.events_range)
                    for i = 1:size(app.events_range,1)
                        plot(app.UIAxes,[app.events_range(i,1):app.events_range(i,2)], app.z(app.events_range(i,1):app.events_range(i,2)),'Color','r')
                    end
                end
                scatter(app.UIAxes, app.locs,app.z(app.locs),20,'c','filled')
                hold(app.UIAxes,'off')
            end
        end

        % Value changed function: Thresh1EditField
        function Thresh1EditFieldValueChanged(app, event)
            app.thresh1 = app.Thresh1EditField.Value;
        end

        % Value changed function: Thresh2EditField
        function Thresh2EditFieldValueChanged(app, event)
            app.thresh2 = app.Thresh2EditField.Value;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            app.Thresh1EditFieldValueChanged(event)
            app.Thresh2EditFieldValueChanged(event)
            app.MinTimesEditFieldValueChanged(event)
            app.MaxTimesEditFieldValueChanged(event)
            app.MinPeakProminenceEditFieldValueChanged(event)

            app.flag = 0;

            if app.highf~=0
                [b, a] = butter(2, app.highf/(app.framerate/2), 'high');
                app.signal = filtfilt(b, a, app.signal_o);
            else
                app.signal = app.signal_o;
            end
            y1 = fft(app.signal);
            f1 = (0:length(y1)-1)*app.framerate/length(y1);
            fourier_f = figure;
            plot(f1,abs(y1))
            xlabel('Frequency (Hz)')
            ylabel('Magnitude')
            title('Fourier tranform')
            xlim([0 2])

            value_thresh1 = median(app.signal) - app.thresh1*mad(app.signal,1);
            filtered_trace = app.signal(app.signal>value_thresh1);
            value_thresh2 = min(filtered_trace) + app.thresh2 * std(app.noise);
            app.events_range = events_finder(app.signal,value_thresh2,app.mintime*app.framerate,app.maxtime*app.framerate);
            app.z = zscore(app.signal);

            if ~isempty(app.events_range)
                [app.pks,app.locs,app.w,app.p] = findpeaks(app.z,'MinPeakProminence',app.Minpeak);
                app.InRange = app.locs>=app.events_range(:,1) & app.locs<=app.events_range(:,2);
                app.locs = app.locs(sum(app.InRange,1)>0);
                app.pks = app.pks(sum(app.InRange,1)>0);
                app.w = app.w(sum(app.InRange,1)>0);
                app.p = app.p(sum(app.InRange,1)>0);
    
                app.RangeSlider.Limits = [0, length(app.signal)];
                app.RangeSlider.Value = [0, length(app.signal)];
                cla(app.UIAxes,'reset')
                plot(app.UIAxes,app.z,'Color','k')
                xlim(app.UIAxes,app.RangeSlider.Value)
                hold(app.UIAxes,'on')
                for i = 1:size(app.events_range,1)
                    plot(app.UIAxes,[app.events_range(i,1):app.events_range(i,2)], app.z(app.events_range(i,1):app.events_range(i,2)),'Color','r')
                end
                scatter(app.UIAxes, app.locs,app.z(app.locs),20,'c','filled')
                hold(app.UIAxes,'off')
            else
                app.RangeSlider.Limits = [0, length(app.signal)];
                app.RangeSlider.Value = [0, length(app.signal)];
                cla(app.UIAxes,'reset')
                plot(app.UIAxes,app.z,'Color','k')
                xlim(app.UIAxes,app.RangeSlider.Value)
            end
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            app.framerate = app.FrameRateEditField.Value;
        end

        % Value changed function: MinTimesEditField
        function MinTimesEditFieldValueChanged(app, event)
            app.mintime = app.MinTimesEditField.Value;
        end

        % Value changed function: MaxTimesEditField
        function MaxTimesEditFieldValueChanged(app, event)
            app.maxtime = app.MaxTimesEditField.Value;
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.start_time = app.StartEditField.Value;
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.end_time = app.EndEditField.Value;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.start_time>0 & app.end_time>0 & app.start_time<app.end_time
                if isempty(app.events_temp)
                    app.events_temp = app.events_range;
                end
                if ~isempty(app.events_temp)
                    if ~ismember([app.start_time, app.end_time], app.events_temp,"rows") 
                        app.events_temp(end+1,:) = [app.start_time, app.end_time];
                        app.events_temp = sortrows(app.events_temp);
                    end
                else
                    app.events_temp(end+1,:) = [app.start_time, app.end_time];
                    app.events_temp = sortrows(app.events_temp);
                end

                [app.pks_temp,app.locs_temp,app.w_temp,app.p_temp] = findpeaks(app.z,'MinPeakProminence',app.Minpeak);
                app.InRange_temp = app.locs_temp>=app.events_temp(:,1) & app.locs_temp<=app.events_temp(:,2);
                app.locs_temp = app.locs_temp(sum(app.InRange_temp,1)>0);
                app.pks_temp = app.pks_temp(sum(app.InRange_temp,1)>0);
                app.w_temp = app.w_temp(sum(app.InRange_temp,1)>0);
                app.p_temp = app.p_temp(sum(app.InRange_temp,1)>0);

                app.RangeSlider.Limits = [0, length(app.signal)];

                cla(app.UIAxes,'reset')
                plot(app.UIAxes,app.z,'Color','k')
                xlim(app.UIAxes,app.RangeSlider.Value)
                hold(app.UIAxes,'on')
                for i = 1:size(app.events_range,1)
                    plot(app.UIAxes,[app.events_temp(i,1):app.events_temp(i,2)], app.z(app.events_temp(i,1):app.events_temp(i,2)),'Color','r')
                end
                scatter(app.UIAxes, app.locs_temp,app.z(app.locs_temp),20,'c','filled')
                hold(app.UIAxes,'off')
                app.flag = 1;
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if app.start_time~=0 & app.end_time~=0 & app.start_time<app.end_time
                if ~isempty(app.events_range)
                    if isempty(app.events_temp)
                        app.events_temp = app.events_range;
                    end
                    [~,ind_start] = min(abs(app.events_temp(:,1)-app.start_time));
                    [~,ind_end] = min(abs(app.events_temp(:,2)-app.end_time));
                    [~,locb] = ismember([app.events_temp(ind_start,1), app.events_temp(ind_end,2)], app.events_temp,'rows');
                    if locb~=0
                        app.events_temp(locb,:) = [];
                    end

                    [app.pks_temp,app.locs_temp,app.w_temp,app.p_temp] = findpeaks(app.z,'MinPeakProminence',app.Minpeak);
                    app.InRange_temp = app.locs_temp>=app.events_temp(:,1) & app.locs_temp<=app.events_temp(:,2);
                    app.locs_temp = app.locs_temp(sum(app.InRange_temp,1)>0);
                    app.pks_temp = app.pks_temp(sum(app.InRange_temp,1)>0);
                    app.w_temp = app.w_temp(sum(app.InRange_temp,1)>0);
                    app.p_temp = app.p_temp(sum(app.InRange_temp,1)>0);

                    app.RangeSlider.Limits = [0, length(app.signal)];
                    
                    cla(app.UIAxes,'reset')
                    plot(app.UIAxes,app.z,'Color','k')
                    xlim(app.UIAxes,app.RangeSlider.Value)
                    hold(app.UIAxes,'on')
                    for i = 1:size(app.events_range,1)
                        plot(app.UIAxes,[app.events_temp(i,1):app.events_temp(i,2)], app.z(app.events_temp(i,1):app.events_temp(i,2)),'Color','r')
                    end
                    scatter(app.UIAxes, app.locs_temp,app.z(app.locs_temp),20,'c','filled')
                    hold(app.UIAxes,'off')
                    app.flag = 1;
                end
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.events_temp = [];
            app.locs_temp = [];
            app.pks_temp = [];
            app.w_temp = [];
            app.p_temp = [];

            app.RangeSlider.Limits = [0, length(app.signal)];

            cla(app.UIAxes,'reset')
            plot(app.UIAxes,app.z,'Color','k')
            xlim(app.UIAxes,app.RangeSlider.Value)
            hold(app.UIAxes,'on')
            for i = 1:size(app.events_range,1)
                plot(app.UIAxes,[app.events_range(i,1):app.events_range(i,2)], app.z(app.events_range(i,1):app.events_range(i,2)),'Color','r')
            end
            scatter(app.UIAxes, app.locs,app.z(app.locs),20,'c','filled')
            hold(app.UIAxes,'off')
            app.flag = 0;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if app.flag~=0
                app.events_range = app.events_temp;
                app.pks = app.pks_temp;
                app.locs = app.locs_temp;
                app.w = app.w_temp;
                app.p = app.p_temp;

                app.pks_raw = app.signal_o(app.locs);
            end

            app.events_temp = [];
            app.locs_temp = [];
            app.pks_temp = [];
            app.w_temp = [];
            app.p_temp = [];
            app.events_per_min = size(app.events_range,1)/(length(app.signal)/app.framerate/60);
            app.EventsminEditFieldValueChanged(event)
            if app.events_per_min==0
                app.peaks_per_event = 0;
            else
                app.peaks_per_event = length(app.pks)/size(app.events_range,1);
            end
            app.PeaksEventEditFieldValueChanged(event)

        end

        % Value changed function: EventsminEditField
        function EventsminEditFieldValueChanged(app, event)
            app.EventsminEditField.Value = app.events_per_min;            
        end

        % Value changed function: PeaksEventEditField
        function PeaksEventEditFieldValueChanged(app, event)
            app.PeaksEventEditField.Value = app.peaks_per_event;            
        end

        % Value changing function: RangeSlider
        function RangeSliderValueChanging(app, event)
            xlim(app.UIAxes,event.Value)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            events_tosave = app.events_range;
            pks_tosave = app.pks_raw;
            locs_tosave = app.locs;
            w_tosave = app.w;
            p_tosave = app.p;
            events_per_min_tosave = app.events_per_min;
            peaks_per_event_tosave = app.peaks_per_event;
            save([app.path,'\events_pks.mat'],"events_tosave","locs_tosave",'pks_tosave','w_tosave',...
                "p_tosave","events_per_min_tosave","peaks_per_event_tosave")
            params = struct('Thresh1',app.thresh1,'Thresh2',app.thresh2,'FrameRate',app.framerate,'Mintime',app.mintime,...
                'Maxtime',app.maxtime,'Minpeak',app.Minpeak,'HighPass',app.highf);
            save([app.path,'\params.mat'],'params')
            fig1 = figure;
            copyobj(app.UIAxes, fig1);
            savefig(fig1,[app.path,'\signal'])
            close(fig1)
        end

        % Value changed function: HighPassCutoffHzEditField
        function HighPassCutoffHzEditFieldValueChanged(app, event)
            app.highf = app.HighPassCutoffHzEditField.Value;
        end

        % Value changed function: MinPeakProminenceEditField
        function MinPeakProminenceEditFieldValueChanged(app, event)
            app.Minpeak = app.MinPeakProminenceEditField.Value;
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            [vfile, vpath] = uigetfile({'*.mp4'; '*.mpg'; '*.avi'}, 'Select Video File');
            app.Lamp.Color='r';
            if vfile~=0
                cd(vpath)
                app.input_video_file = fullfile(vpath,vfile);
                app.EditField_2ValueChanged(event)
                app.vidObj = VideoReader(app.input_video_file);
                app.Slider.Limits = [1 app.vidObj.NumFrames];
                app.Slider.Value = 1;
                app.pp = false;
                app.PlayPauseButton.Value = app.pp;
                app.currentFrame = app.Slider.Value;
                app.SpeedSpinnerValueChanged(event)
                axis(app.Video,'off')
                while (app.currentFrame <= app.vidObj.NumFrames)
                    vframe = read(app.vidObj,app.currentFrame);
                    imshow(vframe,'Parent',app.Video)
                    app.Video.Title.String = sprintf("Current Frame = %d",app.currentFrame);
                    app.Slider.Value = app.currentFrame;
                    pausetime = (1/(app.vidObj.FrameRate*app.vspeed))/0.001;
                    for t = 1:pausetime
                        if app.pp == true
                            pause(0.001)
                        else
                            break
                        end
                    end
                    if app.pp == false
                        while(1)
                            pause(0.01)
                            if app.pp == true
                                break
                            end
                        end
                    else

                    end
                    app.currentFrame = app.currentFrame+1;
                end         
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.EditField_2.Value = app.input_video_file;
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            app.vspeed = app.SpeedSpinner.Value;       
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            app.pp = app.PlayPauseButton.Value;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.currentFrame < app.vidObj.numFrame-100
                app.currentFrame = app.currentFrame + 100;
            end
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            if app.currentFrame > 100
                app.currentFrame = app.currentFrame - 100;
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.currentFrame = floor(event.Value);
        end

        % Button pushed function: LoadBehavioralDataButton
        function LoadBehavioralDataButtonPushed(app, event)
            [bfile, bpath] = uigetfile('.csv', 'Select Behavioral Data');
            app.B = table2array(readtable(fullfile(bpath,bfile)));
            app.FrameRateEditFieldValueChanged(event)
        end

        % Button pushed function: LoadCoordinatesofHolesButton
        function LoadCoordinatesofHolesButtonPushed(app, event)
            [hfile, hpath] = uigetfile('.txt', 'Select Coordinates of Holes');
            app.holexy = table2array(readtable(fullfile(hpath,hfile)));
        end

        % Value changed function: StartFrameEditField
        function StartFrameEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: EndFrameEditField
        function EndFrameEditFieldValueChanged(app, event)
               
        end

        % Value changed function: Height1EditField
        function Height1EditFieldValueChanged(app, event)
                        
        end

        % Value changed function: Height2EditField
        function Height2EditFieldValueChanged(app, event)
                        
        end

        % Value changed function: Width1EditField
        function Width1EditFieldValueChanged(app, event)
                        
        end

        % Value changed function: Width2EditField
        function Width2EditFieldValueChanged(app, event)
                      
        end

        % Button pushed function: CreateTrimmedVideoButton
        function CreateTrimmedVideoButtonPushed(app, event)
            app.startFrame = app.StartFrameEditField.Value;
            app.endFrame = app.EndFrameEditField.Value;
            app.Height1 = app.Height1EditField.Value;
            app.Height2 = app.Height2EditField.Value;
            app.Width1 = app.Width1EditField.Value;
            app.Width2 = app.Width2EditField.Value;
            [app.Trim_time] = trim_video(app.input_video_file, app.startFrame, app.endFrame,...
                app.Height1, app.Height2, app.Width1, app.Width2);
            app.Lamp.Color='g';
        end

        % Value changed function: DiameterofHolescmEditField
        function DiameterofHolescmEditFieldValueChanged(app, event)
            app.Dhole = app.DiameterofHolescmEditField.Value;            
        end

        % Value changed function: HeightpixelEditField
        function HeightpixelEditFieldValueChanged(app, event)
            app.height_maze = app.HeightpixelEditField.Value;
        end

        % Value changed function: WidthpixelEditField
        function WidthpixelEditFieldValueChanged(app, event)
            app.width_maze = app.WidthpixelEditField.Value; 
        end

        % Value changed function: MobilityThreshStartcmsEditField
        function MobilityThreshStartcmsEditFieldValueChanged(app, event)
            app.mobi_start = app.MobilityThreshStartcmsEditField.Value;    
        end

        % Value changed function: MobilityThreshEndcmsEditField
        function MobilityThreshEndcmsEditFieldValueChanged(app, event)
            app.mobi_end = app.MobilityThreshEndcmsEditField.Value;
        end

        % Button pushed function: CalculateBehavioralParametersButton
        function CalculateBehavioralParametersButtonPushed(app, event)
            if ~isempty(app.B) & ~isempty(app.holexy)
                app.StartFrameEditFieldValueChanged(event)
                app.DiameterofHolescmEditFieldValueChanged(event)
                app.holexy_cm = [app.holexy(:,2)*100/app.width_maze, app.holexy(:,3)*100/app.height_maze];
                app.pos_head = [app.B(:,2)*100/app.width_maze, app.B(:,3)*100/app.height_maze];
                app.pos_body = [app.B(:,17)*100/app.width_maze, app.B(:,18)*100/app.height_maze];
                app.Total_Dist = sum(diag(pdist2(app.pos_body(2:end,:),app.pos_body(1:end-1,:))));
                app.DistanceTravelledcmEditFieldValueChanged(event)
                Bd = pdist2(app.pos_head,app.holexy_cm);
                Bd = single(Bd<app.Dhole/2);
                app.ishole = Bd;
                app.Latency_to_Target = findND(app.ishole(:,11),1)/app.framerate;
                app.LatencytoTargetsEditFieldValueChanged(event)
                [app.velocity, app.mobi_state, app.mobi_duration, app.immobi_duration] =...
                    velocity_mobility(app.pos_body, app.mobi_start, app.mobi_end, app.framerate);
                Bd(2:end) = Bd(2:end)-Bd(1:end-1);
                Bd(sum(Bd,2)<=0,:) = [];            
                Bd(2:end) = Bd(2:end)-Bd(1:end-1);
                Bd(Bd==-1)=0;
                Bd(sum(Bd,2)<=0,:) = [];
                app.within_hole = sum(Bd,1);
                if sum(app.ishole,'all')~=0
                    [app.anglec, app.hole_order, app.exp_range, app.entry_count, app.ref] = detect_exploration(app.pos_head, app.ishole);
                end
            end
        end

        % Value changed function: DistanceTravelledcmEditField
        function DistanceTravelledcmEditFieldValueChanged(app, event)
            app.DistanceTravelledcmEditField.Value = app.Total_Dist;            
        end

        % Value changed function: LatencytoTargetsEditField
        function LatencytoTargetsEditFieldValueChanged(app, event)
            if ~isempty(app.Latency_to_Target)
                app.LatencytoTargetsEditField.Value = app.Latency_to_Target; 
            else
                app.LatencytoTargetsEditField.Value = Inf;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            params_b = struct('SignalTrimTime',app.Trim_time,'HeightofMaze',app.height_maze,'WidthofMaze',...
                app.width_maze,'HoleDiameter',app.Dhole,'MobiThreshStart',app.mobi_start,'MobiThreshEnd',app.mobi_end);
            save([app.path,'\params_b.mat'],'params_b')
            trajectories_head  = app.pos_head;
            trajectories_body  = app.pos_body;
            hole_coordinates = app.holexy_cm;
            Total_Dist_tosave = app.Total_Dist;
            Latency_to_Target_tosave = app.Latency_to_Target;
            velocity_tosave = app.velocity;
            mobility_state = app.mobi_state;
            mobility_duration = app.mobi_duration;
            immobility_duration = app.immobi_duration;
            ishole_tosave = app.ishole;
            within_hole_tosave = app.within_hole;
            angle_change = app.anglec;
            hole_order_tosave = app.hole_order;
            exploration_time_range = app.exp_range;
            entry_count_tosave = app.entry_count;
            ref_tosave = app.ref;
            save([app.path,'\behavior.mat'],"trajectories_head","trajectories_body","hole_coordinates",'Total_Dist_tosave',...
                'Latency_to_Target_tosave',"ishole_tosave","within_hole_tosave","angle_change",...
                "hole_order_tosave","exploration_time_range","entry_count_tosave","ref_tosave",...
                "velocity_tosave","mobility_state","mobility_duration","immobility_duration")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectPathButton = uibutton(app.UIFigure, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CommonTab = uitab(app.TabGroup);
            app.CommonTab.Title = 'Common';
            app.UIAxes = uiaxes(app.CommonTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Z-score')
            zlabel(app.UIAxes, 'Z')
            app.MinPeakProminenceEditFieldLabel = uilabel(app.CommonTab);
            app.MinPeakProminenceEditFieldLabel.Text = 'Min Peak Prominence ';
            app.MinPeakProminenceEditField = uieditfield(app.CommonTab, 'numeric');
            app.MinPeakProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakProminenceEditFieldValueChanged, true);
            app.MinPeakProminenceEditField.Value = 0.5;
            app.DetectButton = uibutton(app.CommonTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.RangeSlider = uislider(app.CommonTab, 'range');
            app.RangeSlider.ValueChangingFcn = createCallbackFcn(app, @RangeSliderValueChanging, true);
            app.Thresh1EditFieldLabel = uilabel(app.CommonTab);
            app.Thresh1EditFieldLabel.Text = 'Thresh1';
            app.Thresh1EditField = uieditfield(app.CommonTab, 'numeric');
            app.Thresh1EditField.ValueChangedFcn = createCallbackFcn(app, @Thresh1EditFieldValueChanged, true);
            app.Thresh1EditField.Value = 2;
            app.Thresh2EditFieldLabel = uilabel(app.CommonTab);
            app.Thresh2EditFieldLabel.Text = 'Thresh2';
            app.Thresh2EditField = uieditfield(app.CommonTab, 'numeric');
            app.Thresh2EditField.ValueChangedFcn = createCallbackFcn(app, @Thresh2EditFieldValueChanged, true);
            app.Thresh2EditField.Value = 2;
            app.FrameRateEditFieldLabel = uilabel(app.CommonTab);
            app.FrameRateEditFieldLabel.Text = 'FrameRate';
            app.FrameRateEditField = uieditfield(app.CommonTab, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 30;
            app.MinTimesEditFieldLabel = uilabel(app.CommonTab);
            app.MinTimesEditFieldLabel.Text = 'MinTime/s';
            app.MinTimesEditField = uieditfield(app.CommonTab, 'numeric');
            app.MinTimesEditField.ValueChangedFcn = createCallbackFcn(app, @MinTimesEditFieldValueChanged, true);
            app.MinTimesEditField.Value = 2;
            app.MaxTimesEditFieldLabel = uilabel(app.CommonTab);
            app.MaxTimesEditFieldLabel.Text = 'MaxTime/s';
            app.MaxTimesEditField = uieditfield(app.CommonTab, 'numeric');
            app.MaxTimesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimesEditFieldValueChanged, true);
            app.MaxTimesEditField.Value = 30;
            app.HighPassCutoffHzEditFieldLabel = uilabel(app.CommonTab);
            app.HighPassCutoffHzEditFieldLabel.Text = 'High-Pass Cut-off/Hz';
            app.HighPassCutoffHzEditField = uieditfield(app.CommonTab, 'numeric');
            app.HighPassCutoffHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassCutoffHzEditFieldValueChanged, true);
            app.ManuallyaddremoveeventsPanel = uipanel(app.CommonTab);
            app.ManuallyaddremoveeventsPanel.Title = 'Manually add/remove events';
            app.AddButton = uibutton(app.ManuallyaddremoveeventsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.ManuallyaddremoveeventsPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AbortButton = uibutton(app.ManuallyaddremoveeventsPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.StartEditFieldLabel = uilabel(app.ManuallyaddremoveeventsPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.ManuallyaddremoveeventsPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.ManuallyaddremoveeventsPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.ManuallyaddremoveeventsPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.ConfirmButton = uibutton(app.ManuallyaddremoveeventsPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ResultsPanel = uipanel(app.CommonTab);
            app.ResultsPanel.Title = 'Results';
            app.EventsminEditFieldLabel = uilabel(app.ResultsPanel);
            app.EventsminEditFieldLabel.Text = 'Events/min';
            app.EventsminEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.EventsminEditField.ValueChangedFcn = createCallbackFcn(app, @EventsminEditFieldValueChanged, true);
            app.PeaksEventEditFieldLabel = uilabel(app.ResultsPanel);
            app.PeaksEventEditFieldLabel.Text = 'Peaks/Event';
            app.PeaksEventEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PeaksEventEditField.ValueChangedFcn = createCallbackFcn(app, @PeaksEventEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.CommonTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BehavioralTab = uitab(app.TabGroup);
            app.BehavioralTab.Title = 'Behavioral';
            app.Video = uiaxes(app.BehavioralTab);
            title(app.Video, 'Title')
            zlabel(app.Video, 'Z')
            app.Video.XTick = [];
            app.Video.YTick = [];
            app.SelectVideoButton = uibutton(app.BehavioralTab, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.EditField_2 = uieditfield(app.BehavioralTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Slider = uislider(app.BehavioralTab);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SpeedSpinnerLabel = uilabel(app.BehavioralTab);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.BehavioralTab);
            app.SpeedSpinner.Limits = [1 4];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 1;
            app.PlayPauseButton = uibutton(app.BehavioralTab, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Text = 'Play/Pause';
            app.ForwardButton = uibutton(app.BehavioralTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.RewindButton = uibutton(app.BehavioralTab, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.ResultsDisplayPanel = uipanel(app.BehavioralTab);
            app.ResultsDisplayPanel.Title = 'Results Display';
            app.DistanceTravelledcmEditFieldLabel = uilabel(app.ResultsDisplayPanel);
            app.DistanceTravelledcmEditFieldLabel.Text = 'Distance Travelled/cm';
            app.DistanceTravelledcmEditField = uieditfield(app.ResultsDisplayPanel, 'numeric');
            app.DistanceTravelledcmEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceTravelledcmEditFieldValueChanged, true);
            app.LatencytoTargetsEditFieldLabel = uilabel(app.ResultsDisplayPanel);
            app.LatencytoTargetsEditFieldLabel.Text = 'Latency to Target/s';
            app.LatencytoTargetsEditField = uieditfield(app.ResultsDisplayPanel, 'numeric');
            app.LatencytoTargetsEditField.ValueChangedFcn = createCallbackFcn(app, @LatencytoTargetsEditFieldValueChanged, true);
            app.BehavioralSignalDataTrimPanel = uipanel(app.BehavioralTab);
            app.BehavioralSignalDataTrimPanel.Title = 'Behavioral/Signal Data Trim';
            app.StartFrameEditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.Width2EditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.Width2EditField.ValueChangedFcn = createCallbackFcn(app, @Width2EditFieldValueChanged, true);
            app.Width1EditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.Width1EditField.ValueChangedFcn = createCallbackFcn(app, @Width1EditFieldValueChanged, true);
            app.Height2EditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.Height2EditField.ValueChangedFcn = createCallbackFcn(app, @Height2EditFieldValueChanged, true);
            app.Height1EditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.Height1EditField.ValueChangedFcn = createCallbackFcn(app, @Height1EditFieldValueChanged, true);
            app.EndFrameEditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.EndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrameEditFieldValueChanged, true);
            app.StartFrameEditField = uieditfield(app.BehavioralSignalDataTrimPanel, 'numeric');
            app.StartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrameEditFieldValueChanged, true);
            app.CreateTrimmedVideoButton = uibutton(app.BehavioralSignalDataTrimPanel, 'push');
            app.CreateTrimmedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTrimmedVideoButtonPushed, true);
            app.CreateTrimmedVideoButton.Text = 'Create Trimmed Video';
            app.EndFrameEditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.Height1EditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.Height1EditFieldLabel.Text = 'Height1';
            app.Height2EditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.Height2EditFieldLabel.Text = 'Height2';
            app.Width1EditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.Width1EditFieldLabel.Text = 'Width1';
            app.Width2EditFieldLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.Width2EditFieldLabel.Text = 'Width2';
            app.KeepEndFrameorHeightWidthas0ifnotneededLabel = uilabel(app.BehavioralSignalDataTrimPanel);
            app.KeepEndFrameorHeightWidthas0ifnotneededLabel.Text = 'Keep End Frame or Height/Width as 0 if not needed.';
            app.LoadBehavioralDataButton = uibutton(app.BehavioralTab, 'push');
            app.LoadBehavioralDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBehavioralDataButtonPushed, true);
            app.LoadBehavioralDataButton.Text = 'Load Behavioral Data';
            app.LoadCoordinatesofHolesButton = uibutton(app.BehavioralTab, 'push');
            app.LoadCoordinatesofHolesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoordinatesofHolesButtonPushed, true);
            app.LoadCoordinatesofHolesButton.Text = 'Load Coordinates of Holes';
            app.SaveButton_2 = uibutton(app.BehavioralTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ScaleCorrectionPanel = uipanel(app.BehavioralTab);
            app.ScaleCorrectionPanel.Title = 'Scale Correction';
            app.DiameterofHolescmEditFieldLabel = uilabel(app.ScaleCorrectionPanel);
            app.DiameterofHolescmEditFieldLabel.Text = 'Diameter of Holes/cm';
            app.DiameterofHolescmEditField = uieditfield(app.ScaleCorrectionPanel, 'numeric');
            app.DiameterofHolescmEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterofHolescmEditFieldValueChanged, true);
            app.DiameterofHolescmEditField.Value = 9;
            app.HeightpixelEditFieldLabel = uilabel(app.ScaleCorrectionPanel);
            app.HeightpixelEditFieldLabel.Text = 'Height/pixel';
            app.HeightpixelEditField = uieditfield(app.ScaleCorrectionPanel, 'numeric');
            app.HeightpixelEditField.ValueChangedFcn = createCallbackFcn(app, @HeightpixelEditFieldValueChanged, true);
            app.WidthpixelEditFieldLabel = uilabel(app.ScaleCorrectionPanel);
            app.WidthpixelEditFieldLabel.Text = 'Width/pixel';
            app.WidthpixelEditField = uieditfield(app.ScaleCorrectionPanel, 'numeric');
            app.WidthpixelEditField.ValueChangedFcn = createCallbackFcn(app, @WidthpixelEditFieldValueChanged, true);
            app.MobilityThreshStartcmsEditFieldLabel = uilabel(app.ScaleCorrectionPanel);
            app.MobilityThreshStartcmsEditFieldLabel.Text = 'Mobility Thresh Start (cm/s)';
            app.MobilityThreshStartcmsEditField = uieditfield(app.ScaleCorrectionPanel, 'numeric');
            app.MobilityThreshStartcmsEditField.ValueChangedFcn = createCallbackFcn(app, @MobilityThreshStartcmsEditFieldValueChanged, true);
            app.MobilityThreshStartcmsEditField.Value = 2;
            app.MobilityThreshEndcmsEditFieldLabel = uilabel(app.ScaleCorrectionPanel);
            app.MobilityThreshEndcmsEditFieldLabel.Text = 'Mobility Thresh End (cm/s)';
            app.MobilityThreshEndcmsEditField = uieditfield(app.ScaleCorrectionPanel, 'numeric');
            app.MobilityThreshEndcmsEditField.ValueChangedFcn = createCallbackFcn(app, @MobilityThreshEndcmsEditFieldValueChanged, true);
            app.MobilityThreshEndcmsEditField.Value = 1.75;
            app.CalculateBehavioralParametersButton = uibutton(app.BehavioralTab, 'push');
            app.CalculateBehavioralParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBehavioralParametersButtonPushed, true);
            app.CalculateBehavioralParametersButton.Text = 'Calculate Behavioral Parameters';
            app.Lamp = uilamp(app.BehavioralTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fp_processing_vesion3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end